classdef updated_app < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout      matlab.ui.container.GridLayout
        YOUWINLabel     matlab.ui.control.Label
        YOULOSELabel    matlab.ui.control.Label
        row_5_column_4  matlab.ui.control.Label
        row_4_column_4  matlab.ui.control.Label
        row_3_column_4  matlab.ui.control.Label
        row_2_column_4  matlab.ui.control.Label
        row_1_column_4  matlab.ui.control.Label
        WORDLELabel     matlab.ui.control.Label
        row_5_column_1  matlab.ui.control.Label
        row_4_column_1  matlab.ui.control.Label
        row_3_column_1  matlab.ui.control.Label
        row_2_column_1  matlab.ui.control.Label
        row_1_column_1  matlab.ui.control.Label
        edit_field      matlab.ui.control.EditField
        submitButton    matlab.ui.control.Button
        row_5_column_5  matlab.ui.control.Label
        row_4_column_5  matlab.ui.control.Label
        row_3_column_5  matlab.ui.control.Label
        row_2_column_5  matlab.ui.control.Label
        row_1_column_5  matlab.ui.control.Label
        row_5_column_3  matlab.ui.control.Label
        row_4_column_3  matlab.ui.control.Label
        row_3_column_3  matlab.ui.control.Label
        row_2_column_3  matlab.ui.control.Label
        row_1_column_3  matlab.ui.control.Label
        row_5_column_2  matlab.ui.control.Label
        row_4_column_2  matlab.ui.control.Label
        row_3_column_2  matlab.ui.control.Label
        row_2_column_2  matlab.ui.control.Label
        row_1_column_2  matlab.ui.control.Label
        guess_the_word  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: submitButton
        function submitButtonPushed(comp, event)
   
    
    persistent pressCount 
    if isempty(pressCount)
        pressCount = 0;
    end
    
    pressCount = pressCount + 1;
    
    % Create a matrix to hold the values
    valueMatrix = comp.edit_field.Value(1, :); % Assuming edit_field.Value is a 1x4 array
    
    % Use a matrix to reference the row and column dynamically
    row = pressCount;
    
    if row <= 5  % Ensure the row doesn't exceed the 5th row
        for col = 1:5  % Loop through columns 1 to 5
            comp.(['row_' num2str(row) '_column_' num2str(col)]).Text = valueMatrix(col);
        end
    end

    green_color = [0 1 0];
    yellow_color = [1 1 0];
    red_color = [1 0 0];

    correct_word = 'paris';
    green_letters = [0 0 0 0 0];
    yellow_letters = [0 0 0 0 0];
    %% notes: how to split the correct word, need to not have the issue with 'ednn', how to apply contains OR ismember OR strcmp
    %% create variable for ['row_' num2str(i) '_column_' num2str(j)], add isempty
    %% create loop or if statement that removes letters from split string that have been guessed AND are in the right spot
for j = 1:5
    if correct_word(j) == comp.edit_field.Value(j)
    green_letters(j) = 1;
    end
end
for j = 1:5 
    if green_letters(j) == 1
        continue;
    end
    if ismember(comp.edit_field.Value(j),correct_word(green_letters == 0))
        yellow_letters(j) = 1;
    end 
end 

for j = 1:5
    if green_letters(j) == 1
        set(comp.(['row_' num2str(row) '_column_' num2str(j)]),'BackgroundColor', green_color);
    continue; 
    end
    if yellow_letters(j) == 1
set(comp.(['row_' num2str(row) '_column_' num2str(j)]), 'BackgroundColor', yellow_color); 
        continue;
    end
    set(comp.(['row_' num2str(row) '_column_' num2str(j)]), 'BackgroundColor', red_color);  
end 


        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 624 475];
            comp.GridLayout = uigridlayout(comp);
            comp.guess_the_word = uilabel(comp.GridLayout);
            comp.guess_the_word.Text = {'Guess the word and check your answer by pressing the submit button.'; 'lowercase letters only!'};
            comp.row_1_column_2 = uilabel(comp.GridLayout);
            comp.row_1_column_2.Text = '';
            comp.row_2_column_2 = uilabel(comp.GridLayout);
            comp.row_2_column_2.Text = '';
            comp.row_3_column_2 = uilabel(comp.GridLayout);
            comp.row_3_column_2.Text = '';
            comp.row_4_column_2 = uilabel(comp.GridLayout);
            comp.row_4_column_2.Text = '';
            comp.row_5_column_2 = uilabel(comp.GridLayout);
            comp.row_5_column_2.Text = '';
            comp.row_1_column_3 = uilabel(comp.GridLayout);
            comp.row_1_column_3.Text = '';
            comp.row_2_column_3 = uilabel(comp.GridLayout);
            comp.row_2_column_3.Text = '';
            comp.row_3_column_3 = uilabel(comp.GridLayout);
            comp.row_3_column_3.Text = '';
            comp.row_4_column_3 = uilabel(comp.GridLayout);
            comp.row_4_column_3.Text = '';
            comp.row_5_column_3 = uilabel(comp.GridLayout);
            comp.row_5_column_3.Text = '';
            comp.row_1_column_5 = uilabel(comp.GridLayout);
            comp.row_1_column_5.Text = '';
            comp.row_2_column_5 = uilabel(comp.GridLayout);
            comp.row_2_column_5.Text = '';
            comp.row_3_column_5 = uilabel(comp.GridLayout);
            comp.row_3_column_5.Text = '';
            comp.row_4_column_5 = uilabel(comp.GridLayout);
            comp.row_4_column_5.Text = '';
            comp.row_5_column_5 = uilabel(comp.GridLayout);
            comp.row_5_column_5.Text = '';
            comp.submitButton = uibutton(comp.GridLayout, 'push');
            comp.submitButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @submitButtonPushed, true);
            comp.submitButton.Text = 'submit';
            comp.edit_field = uieditfield(comp.GridLayout, 'text');
            comp.edit_field.CharacterLimits = [0 5];
            comp.edit_field.InputType = 'letters';
            comp.row_1_column_1 = uilabel(comp.GridLayout);
            comp.row_1_column_1.Text = '';
            comp.row_2_column_1 = uilabel(comp.GridLayout);
            comp.row_2_column_1.Text = '';
            comp.row_3_column_1 = uilabel(comp.GridLayout);
            comp.row_3_column_1.Text = '';
            comp.row_4_column_1 = uilabel(comp.GridLayout);
            comp.row_4_column_1.Text = '';
            comp.row_5_column_1 = uilabel(comp.GridLayout);
            comp.row_5_column_1.Text = '';
            comp.WORDLELabel = uilabel(comp.GridLayout);
            comp.WORDLELabel.Text = 'WORDLE';
            comp.row_1_column_4 = uilabel(comp.GridLayout);
            comp.row_1_column_4.Text = '';
            comp.row_2_column_4 = uilabel(comp.GridLayout);
            comp.row_2_column_4.Text = '';
            comp.row_3_column_4 = uilabel(comp.GridLayout);
            comp.row_3_column_4.Text = '';
            comp.row_4_column_4 = uilabel(comp.GridLayout);
            comp.row_4_column_4.Text = '';
            comp.row_5_column_4 = uilabel(comp.GridLayout);
            comp.row_5_column_4.Text = '';
            comp.YOULOSELabel = uilabel(comp.GridLayout);
            comp.YOULOSELabel.Visible = 'off';
            comp.YOULOSELabel.Text = 'YOU LOSE!';
            comp.YOUWINLabel = uilabel(comp.GridLayout);
            comp.YOUWINLabel.Visible = 'off';
            comp.YOUWINLabel.Text = 'YOU WIN! YAYYYYY';
        end
    end
end